classdef tcav_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CloseAppButton                matlab.ui.control.Button
        TCAVGUILabel                  matlab.ui.control.Label
        GlobalOriginLabel             matlab.ui.control.Label
        EditField11                   matlab.ui.control.EditField
        EditField64                   matlab.ui.control.EditField
        EditField22                   matlab.ui.control.EditField
        ROILabel_2                    matlab.ui.control.Label
        EditField21                   matlab.ui.control.EditField
        EditField54                   matlab.ui.control.EditField
        EditField44                   matlab.ui.control.EditField
        EditField34                   matlab.ui.control.EditField
        EditField63                   matlab.ui.control.EditField
        EditField53                   matlab.ui.control.EditField
        EditField43                   matlab.ui.control.EditField
        EditField12                   matlab.ui.control.EditField
        EditField33                   matlab.ui.control.EditField
        EditField62                   matlab.ui.control.EditField
        EditField52                   matlab.ui.control.EditField
        EditField13                   matlab.ui.control.EditField
        EditField23                   matlab.ui.control.EditField
        EditField42                   matlab.ui.control.EditField
        EditField32                   matlab.ui.control.EditField
        OriginLabel_2                 matlab.ui.control.Label
        EditField61                   matlab.ui.control.EditField
        EditField51                   matlab.ui.control.EditField
        EditField41                   matlab.ui.control.EditField
        EditField31                   matlab.ui.control.EditField
        xLabel                        matlab.ui.control.Label
        yLabel                        matlab.ui.control.Label
        unitLabel                     matlab.ui.control.Label
        DefaulltBeamLabel             matlab.ui.control.Label
        ROISpanLabel                  matlab.ui.control.Label
        EditField24                   matlab.ui.control.EditField
        EditField14                   matlab.ui.control.EditField
        CurrentBeamLabel              matlab.ui.control.Label
        isRawLabel_2                  matlab.ui.control.Label
        TCAVControlPanel              matlab.ui.container.Panel
        TabGroup_tcav                 matlab.ui.container.TabGroup
        Sector20Tab                   matlab.ui.container.Tab
        OnButton_0                    matlab.ui.control.Button
        OffButton_0                   matlab.ui.control.Button
        PhaseDESEditFieldLabel_0      matlab.ui.control.Label
        PhaseRBVEditFieldLabel_0      matlab.ui.control.Label
        PhaseRBVEditField_0           matlab.ui.control.NumericEditField
        PhaseradEditFieldLabel        matlab.ui.control.Label
        PhaseDESEditField_0           matlab.ui.control.NumericEditField
        AmplitudeRBVEditFieldLabel_0  matlab.ui.control.Label
        AmplitudeDESEditFieldLabel_0  matlab.ui.control.Label
        AmplitudeRBVEditField_0       matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel      matlab.ui.control.Label
        AmplitudeDESEditField_0       matlab.ui.control.NumericEditField
        TCAVStatusEditFieldLabel      matlab.ui.control.Label
        TCAVStatusEditField_0         matlab.ui.control.EditField
        AutophaseButton_0             matlab.ui.control.Button
        Sector10Tab                   matlab.ui.container.Tab
        OnButton_1                    matlab.ui.control.Button
        OffButton_1                   matlab.ui.control.Button
        TCAVStatusEditFieldLabel_1    matlab.ui.control.Label
        TCAVStatusEditField_1         matlab.ui.control.EditField
        PhaseDESEditFieldLabel_1      matlab.ui.control.Label
        PhaseRBVEditFieldLabel_1      matlab.ui.control.Label
        PhaseRBVEditField_1           matlab.ui.control.NumericEditField
        PhaseradEditFieldLabel_2      matlab.ui.control.Label
        PhaseDESEditField_1           matlab.ui.control.NumericEditField
        AmplitudeRBVEditFieldLabel_1  matlab.ui.control.Label
        AmplitudeDESEditFieldLabel_1  matlab.ui.control.Label
        AmplitudeRBVEditField_1       matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel_2    matlab.ui.control.Label
        AmplitudeDESEditField_1       matlab.ui.control.NumericEditField
        AutophaseButton_1             matlab.ui.control.Button
        CalibrationDAQPanel           matlab.ui.container.Panel
        PhaseEditFieldLabel           matlab.ui.control.Label
        PhaseStartEditField           matlab.ui.control.NumericEditField
        PhaseStopEditField            matlab.ui.control.NumericEditField
        PhaseStepsEditField           matlab.ui.control.NumericEditField
        DAQsettingsEditFieldLabel_2   matlab.ui.control.Label
        DAQsettingsEditFieldLabel_3   matlab.ui.control.Label
        DAQsettingsEditFieldLabel_4   matlab.ui.control.Label
        StartScanButton               matlab.ui.control.Button
        CalibrateButton               matlab.ui.control.Button
        ScanValuesTextAreaLabel       matlab.ui.control.Label
        ScanValuesTextArea            matlab.ui.control.TextArea
        RunEditFieldLabel             matlab.ui.control.Label
        RunEditField                  matlab.ui.control.NumericEditField
        ExperimentEditFieldLabel      matlab.ui.control.Label
        ExperimentEditField           matlab.ui.control.EditField
        CameraViewerPanel             matlab.ui.container.Panel
        UIAxes2                       matlab.ui.control.UIAxes
        StartButton_2                 matlab.ui.control.Button
        SelectBeamButton              matlab.ui.control.StateButton
        SubtractBackgroundCheckBox    matlab.ui.control.CheckBox
        PVDisplay                     matlab.ui.control.EditField
        SelectcameraDropDownLabel     matlab.ui.control.Label
        SelectCameraDropDown          matlab.ui.control.DropDown
        ResolutionbitsSpinnerLabel    matlab.ui.control.Label
        ResolutionbitsSpinner         matlab.ui.control.Spinner
        CalibrateEnergyCheckBox       matlab.ui.control.CheckBox
        CalibrateTimeCheckBox         matlab.ui.control.CheckBox
        ShowRawCheckBox               matlab.ui.control.CheckBox
        ShowBMCheckBox                matlab.ui.control.CheckBox
        TabGroup                      matlab.ui.container.TabGroup
        TimeProfileTab                matlab.ui.container.Tab
        UIAxesTop                     matlab.ui.control.UIAxes
        EnergyProfileTab              matlab.ui.container.Tab
        UIAxesRight                   matlab.ui.control.UIAxes
        PhaseCalibTab                 matlab.ui.container.Tab
        UIAxesPhaseCalib              matlab.ui.control.UIAxes
        PhaseCalibPVFilterTab         matlab.ui.container.Tab
        UIAxesPhaseCalibPVFilter      matlab.ui.control.UIAxes
        TimeCalibTab                  matlab.ui.container.Tab
        UIAxesTimeCalib               matlab.ui.control.UIAxes
        LogPanel                      matlab.ui.container.Panel
        LogTextArea                   matlab.ui.control.TextArea
        ClearLogButton                matlab.ui.control.Button
        AnalysisPanel                 matlab.ui.container.Panel
        BunchLengthCalibrationButton  matlab.ui.control.Button
    end

    properties (Access = public)
        context
    end
    
    properties (Access = private)
        aobj
        aobj_phase
        aobj_ctr_tcav_0
        aobj_ctr_tcav_1
        aobj_time
        
        isOn = 0
        PVs_dict
        TCAV_dict
    end
    
    methods (Access = private)
        
        function UIFigureCloseRequest(app, src, event)
            
            stop(app.aobj.PVtimerCam);
            delete(app.aobj.PVtimerCam);

            stop(app.aobj_ctr_tcav_0.pvlist);
            stop(app.aobj_ctr_tcav_1.pvlist);

            Cleanup(app.aobj_ctr_tcav_0.pvlist);
            Cleanup(app.aobj_ctr_tcav_1.pvlist);
            delete(app)
        end
    end
    
    methods (Access = public)   
        
        function updateTopProfilePlot(app, data, color)
            plot(app.UIAxesTop, data, color);
            if app.aobj.showBMOn && app.aobj.pois.beam.x > 0 && ...
                    app.aobj.pois.beam.x <= abs(app.aobj.pois.raw.roiXNP)
                xline(app.UIAxesTop, app.aobj.pois.beam.x, 'y', 'LineWidth', 2);
            end
            
            % match axes
            xticks_main = str2double(app.UIAxes2.XTickLabel);
            xlabel_main = app.UIAxes2.XLabel.String;
            
            xlabel(app.UIAxesTop, xlabel_main);
            app.UIAxesTop.XTickLabel = arrayfun(@(x) sprintf('%.2f', x), xticks_main, 'UniformOutput', false);
            app.UIAxesTop.XAxis.TickValues = app.UIAxes2.XAxis.TickValues;
            drawnow            
        end
        function updateRightProfilePlot(app, data, color)
            %plot(app.UIAxesRight, data, 1:numel(data), color);
            plot(app.UIAxesRight, flip(data), color);

            if app.aobj.showBMOn && app.aobj.pois.beam.y > 0 && ...
                    app.aobj.pois.beam.y <= abs(app.aobj.pois.raw.roiYNP)
                xline(app.UIAxesRight, app.aobj.pois.beam.y, 'y', 'LineWidth', 2);
            end
            
            % match axes
            %yticks_main = str2double(app.UIAxes2.YTickLabel);
            %ylabel_main = app.UIAxes2.YLabel.String;
                        
            %ylabel(app.UIAxesRight, ylabel_main);
            %app.UIAxesRight.YTickLabel = arrayfun(@(x) sprintf('%.2f', x), yticks_main, 'UniformOutput', false);
            %app.UIAxesRight.YAxis.TickValues = app.UIAxes2.YAxis.TickValues;
            
            xticks_main = str2double(app.UIAxes2.YTickLabel);
            xlabel_main = app.UIAxes2.YLabel.String;
                        
            xlabel(app.UIAxesRight, xlabel_main);
            app.UIAxesRight.XTickLabel = arrayfun(@(x) sprintf('%.2f', x), xticks_main, 'UniformOutput', false);
            app.UIAxesRight.XAxis.TickValues = app.UIAxes2.YAxis.TickValues;           
            app.UIAxesRight.XDir = 'reverse';
            
            drawnow            
        end
        
        function updateCursor(app)
          % Get the current point relative to UIAxes2
          cp = app.UIAxesRight.CurrentPoint;   % 2x3 array
          x = cp(1,1);
          y = cp(1,2);

        end
        
        function SelectBeamActivate(app, event)
                % if there is a plot on axis
                hook = findobj(app.UIAxes2, 'Type', 'image');
                if ~isempty(hook)
                    if app.aobj.selectBeamOn
            
                        % Enable clicks
                        hook.ButtonDownFcn = @(src, event) app.imageClicked(src, event);
                        hook.PickableParts = 'all';
                        hook.HitTest = 'on';
                    else
                        % Disable clicks
                        hook.ButtonDownFcn = [];
                        hook.PickableParts = 'none';
                        hook.HitTest = 'off';
                    end
                end      
        end

        function imageClicked(app, src, event)
            ax = ancestor(src, 'axes');
            coords = ax.CurrentPoint(1,1:2); % [x y]
            x = coords(1);
            y = coords(2);

            % Just for debugging
            %disp(['Clicked at X=' num2str(x) ', Y=' num2str(y)]);
            app.LogTextArea.Value = [app.LogTextArea.Value;...
                      {['Clicked at X=' num2str(x) ', Y=' num2str(y)]}];
                  
            % Draw a red circle where you clicked
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, x, y, 'r+', 'MarkerSize', 5, 'LineWidth', 1.5);
            hold(app.UIAxes2, 'off');
            
            % get selected coord relative to default beam coords

            [app.aobj.pois.beam.relx,app.aobj.pois.beam.rely, app.aobj.pois.beam.isRaw] = deal(x-app.aobj.pois.defaultBeam.x, y-app.aobj.pois.defaultBeam.y, app.aobj.showRawOn);
                                
             %app.aobj.printPOIs();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % call destructors when pressing x button to quit app
            app.UIFigure.CloseRequestFcn = @(src, event) app.UIFigureCloseRequest(src, event);

            %app.UIAxes2.XAxis.Visible = 'off';
            %app.UIAxes2.YAxis.Visible = 'off';
            axis(app.UIAxes2, 'image'); 
            axis(app.UIAxesTop, 'tight');
            axis(app.UIAxesRight, 'tight');
            app.UIAxesRight.YLabel.Rotation = 90;    
            set(app.UIAxesRight, 'YDir', 'reverse');
            %tim maxwell auto phasing
            %populate camera dropdown
            app.PVs_dict = PVConfig();
            app.TCAV_dict = TCAVConfig();
            PVs_dict_keys = keys(app.PVs_dict);
            app.SelectCameraDropDown.Items = PVs_dict_keys;
            app.SelectCameraDropDown.Value = PVs_dict_keys(1);
            app.PVDisplay.Value = app.PVs_dict(app.SelectCameraDropDown.Value);
            
            % sets app.aobj.cameraPV 
            app.aobj = tcav_gui_backend(app);
            app.aobj_phase = tcav_gui_phase_calib(app); 
            
            % add callback to tab change
            app.aobj_phase.tcav_params = app.TCAV_dict('20');
            app.context = PV.Initialize(PVtype.EPICS);
            app.aobj_ctr_tcav_0 = tcav_gui_tcav_0_control(app);
            app.aobj_ctr_tcav_1 = tcav_gui_tcav_1_control(app);
            
            
            app.aobj_time = scanFunc_tcav_bunch_length(app);

        end

        % Button pushed function: CloseAppButton
        function CloseAppButtonPushed(app, event)
            app.UIFigureCloseRequest()
        end

        % Button pushed function: StartButton_2
        function StartButtonUIAxes2Pushed(app, event)
            
            % switch on off state
            app.isOn = ~app.isOn;
            
            if app.aobj.plottingOnUIAxes2 == false
              app.aobj.plottingOnUIAxes2 = true;
              app.StartButton_2.Text = 'Stop';
              app.StartButton_2.BackgroundColor = [0.6353 0.0784 0.1843];
            else
              app.aobj.plottingOnUIAxes2 = false;
              app.StartButton_2.Text = 'Start';
              app.StartButton_2.BackgroundColor = [0.1333 0.349 0.0627];
            end

                        
            % enable select beam functionality
            app.SelectBeamButton.Enable = 'on';
        end

        % Value changed function: ShowBMCheckBox
        function ShowBMCheckBoxValueChanged(app, event)
            app.aobj.showBMOn = app.ShowBMCheckBox.Value;
        end

        % Value changed function: ShowRawCheckBox
        function ShowRawCheckBoxValueChanged(app, event)
            app.aobj.showRawOn = app.ShowRawCheckBox.Value;
        end

        % Value changed function: CalibrateEnergyCheckBox
        function CalibrateEnergyCheckBoxValueChanged(app, event)
            app.aobj.calEOn = app.CalibrateEnergyCheckBox.Value;
        end

        % Value changed function: CalibrateTimeCheckBox
        function CalibrateTimeCheckBoxValueChanged(app, event)
            app.aobj.calTOn = app.CalibrateTimeCheckBox.Value;
            
        end

        % Value changed function: ResolutionbitsSpinner
        function ResolutionbitsSpinnerValueChanged(app, event)
            app.aobj.bitDepth = app.ResolutionbitsSpinner.Value;

        end

        % Value changed function: SelectCameraDropDown
        function SelectCameraDropDownValueChanged(app, event)
            cameraName = app.SelectCameraDropDown.Value;
            app.aobj.cameraPV = app.PVs_dict(cameraName);
            app.PVDisplay.Value = app.aobj.cameraPV;
            app.aobj.newPlot = true;
            
            % disable beam selection
            app.SelectBeamButton.Value = false;
            app.aobj.selectBeamOn = false;
            app.SelectBeamActivate(event);
            
            % if i stop and change cams disable select beam button
            if app.SelectBeamButton.Enable && ~app.isOn
                app.SelectBeamButton.Enable = 'off';
            end
            
        end

        % Value changed function: SelectBeamButton
        function SelectBeamButtonValueChanged(app, event)
            app.aobj.selectBeamOn = app.SelectBeamButton.Value;
            
            % if i stop plotting i can still select on last frame
            % if new cam but not yet started, disable select on last frame
            if ~app.aobj.plottingOn && ~app.aobj.newPlot
                app.SelectBeamActivate(event);
            end
        end

        % Value changed function: SubtractBackgroundCheckBox
        function SubtractBackgroundCheckBoxValueChanged(app, event)
            app.aobj.subtractBGOn = app.SubtractBackgroundCheckBox.Value;
            
        end

        % Value changed function: PhaseStartEditField
        function PhaseStartEditFieldValueChanged(app, event)
            app.aobj_phase.showScanSteps();
        end

        % Value changed function: PhaseStopEditField
        function PhaseStopEditFieldValueChanged(app, event)
            app.aobj_phase.showScanSteps();
            drawnow;
        end

        % Value changed function: PhaseStepsEditField
        function PhaseStepsEditFieldValueChanged(app, event)
            app.aobj_phase.showScanSteps();
            drawnow;
        end

        % Button pushed function: StartScanButton
        function StartScanButtonPushed(app, event)
            app.aobj_phase.launchPhaseScan();
            drawnow;
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            % clear relevant plots
            cla(app.UIAxesPhaseCalib);
            cla(app.UIAxesPhaseCalibPVFilter);
            drawnow;
            
            % read run and exp fields
            run = app.RunEditField.Value;
            exp = app.ExperimentEditField.Value;
            
            % do calibration
            if run == 0 || strcmp(exp, "")
                calibrate_phase_position(app);
            else
                calibrate_phase_position(app, exp, run);
            end
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            app.LogTextArea.Value = {''};
        end

        % Button pushed function: BunchLengthCalibrationButton
        function BunchLengthCalibrationButtonPushed(app, event)
            % clear relevant plots
            cla(app.UIAxesTimeCalib);
            drawnow;    
            
            % do calibration
            app.aobj_time.calibrate_time(app);            
        end

        % Button pushed function: OnButton_0
        function OnButton_0Pushed(app, event)
            control_klysStatSet('KLYS:LI20:41', 1);
            app.LogTextArea.Value = [app.LogTextArea.Value; {'[tcav_gui.mlapp] TCAV 0 ON.'}];
        end

        % Button pushed function: OffButton_0
        function OffButton_0Pushed(app, event)
            control_klysStatSet('KLYS:LI20:41', 0);
            app.LogTextArea.Value = [app.LogTextArea.Value; {'[tcav_gui.mlapp] TCAV 0 OFF.'}];
        end

        % Button pushed function: OnButton_1
        function OnButton_1Pushed(app, event)
            control_klysStatSet('KLYS:LI10:51', 1);
            app.LogTextArea.Value = [app.LogTextArea.Value; {'[tcav_gui.mlapp] TCAV 1 ON.'}];            
        end

        % Button pushed function: OffButton_1
        function OffButton_1Pushed(app, event)
            control_klysStatSet('KLYS:LI10:51', 0);
            app.LogTextArea.Value = [app.LogTextArea.Value; {'[tcav_gui.mlapp] TCAV 1 OFF.'}];           
        end

        % Selection change function: TabGroup_tcav
        function TabGroup_tcavSelectionChanged(app, event)
            selectedTab = app.TabGroup_tcav.SelectedTab;
            if selectedTab == app.Sector10Tab
                app.aobj_phase.tcav_params = app.TCAV_dict('10');
            elseif selectedTab == app.Sector20Tab
                app.aobj_phase.tcav_params = app.TCAV_dict('20');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CloseAppButton = uibutton(app.UIFigure, 'push');
            app.CloseAppButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAppButtonPushed, true);
            app.CloseAppButton.Text = 'Close App';
            app.TCAVGUILabel = uilabel(app.UIFigure);
            app.TCAVGUILabel.Text = 'TCAV GUI';
            app.GlobalOriginLabel = uilabel(app.UIFigure);
            app.GlobalOriginLabel.Text = 'Global Origin';
            app.EditField11 = uieditfield(app.UIFigure, 'text');
            app.EditField11.Editable = 'off';
            app.EditField64 = uieditfield(app.UIFigure, 'text');
            app.EditField64.Editable = 'off';
            app.EditField22 = uieditfield(app.UIFigure, 'text');
            app.EditField22.Editable = 'off';
            app.ROILabel_2 = uilabel(app.UIFigure);
            app.ROILabel_2.Text = 'ROI';
            app.EditField21 = uieditfield(app.UIFigure, 'text');
            app.EditField21.Editable = 'off';
            app.EditField54 = uieditfield(app.UIFigure, 'text');
            app.EditField54.Editable = 'off';
            app.EditField44 = uieditfield(app.UIFigure, 'text');
            app.EditField44.Editable = 'off';
            app.EditField34 = uieditfield(app.UIFigure, 'text');
            app.EditField34.Editable = 'off';
            app.EditField63 = uieditfield(app.UIFigure, 'text');
            app.EditField63.Editable = 'off';
            app.EditField53 = uieditfield(app.UIFigure, 'text');
            app.EditField53.Editable = 'off';
            app.EditField43 = uieditfield(app.UIFigure, 'text');
            app.EditField43.Editable = 'off';
            app.EditField12 = uieditfield(app.UIFigure, 'text');
            app.EditField12.Editable = 'off';
            app.EditField33 = uieditfield(app.UIFigure, 'text');
            app.EditField33.Editable = 'off';
            app.EditField62 = uieditfield(app.UIFigure, 'text');
            app.EditField62.Editable = 'off';
            app.EditField52 = uieditfield(app.UIFigure, 'text');
            app.EditField52.Editable = 'off';
            app.EditField13 = uieditfield(app.UIFigure, 'text');
            app.EditField13.Editable = 'off';
            app.EditField23 = uieditfield(app.UIFigure, 'text');
            app.EditField23.Editable = 'off';
            app.EditField42 = uieditfield(app.UIFigure, 'text');
            app.EditField42.Editable = 'off';
            app.EditField32 = uieditfield(app.UIFigure, 'text');
            app.EditField32.Editable = 'off';
            app.OriginLabel_2 = uilabel(app.UIFigure);
            app.OriginLabel_2.Text = 'Origin';
            app.EditField61 = uieditfield(app.UIFigure, 'text');
            app.EditField61.Editable = 'off';
            app.EditField51 = uieditfield(app.UIFigure, 'text');
            app.EditField51.Editable = 'off';
            app.EditField41 = uieditfield(app.UIFigure, 'text');
            app.EditField41.Editable = 'off';
            app.EditField31 = uieditfield(app.UIFigure, 'text');
            app.EditField31.Editable = 'off';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.unitLabel = uilabel(app.UIFigure);
            app.unitLabel.Text = 'unit';
            app.DefaulltBeamLabel = uilabel(app.UIFigure);
            app.DefaulltBeamLabel.Text = 'Defaullt Beam';
            app.ROISpanLabel = uilabel(app.UIFigure);
            app.ROISpanLabel.Text = 'ROI Span';
            app.EditField24 = uieditfield(app.UIFigure, 'text');
            app.EditField24.Editable = 'off';
            app.EditField14 = uieditfield(app.UIFigure, 'text');
            app.EditField14.Editable = 'off';
            app.CurrentBeamLabel = uilabel(app.UIFigure);
            app.CurrentBeamLabel.Text = 'Current Beam';
            app.isRawLabel_2 = uilabel(app.UIFigure);
            app.isRawLabel_2.Text = 'isRaw';
            app.TCAVControlPanel = uipanel(app.UIFigure);
            app.TCAVControlPanel.Title = 'TCAV Control';
            app.TabGroup_tcav = uitabgroup(app.TCAVControlPanel);
            app.TabGroup_tcav.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_tcavSelectionChanged, true);
            app.Sector20Tab = uitab(app.TabGroup_tcav);
            app.Sector20Tab.Title = 'Sector 20';
            app.OnButton_0 = uibutton(app.Sector20Tab, 'push');
            app.OnButton_0.ButtonPushedFcn = createCallbackFcn(app, @OnButton_0Pushed, true);
            app.OnButton_0.Text = 'On';
            app.OffButton_0 = uibutton(app.Sector20Tab, 'push');
            app.OffButton_0.ButtonPushedFcn = createCallbackFcn(app, @OffButton_0Pushed, true);
            app.OffButton_0.Text = 'Off';
            app.PhaseDESEditFieldLabel_0 = uilabel(app.Sector20Tab);
            app.PhaseDESEditFieldLabel_0.Text = 'DES';
            app.PhaseRBVEditFieldLabel_0 = uilabel(app.Sector20Tab);
            app.PhaseRBVEditFieldLabel_0.Text = 'RBV';
            app.PhaseRBVEditField_0 = uieditfield(app.Sector20Tab, 'numeric');
            app.PhaseRBVEditField_0.Editable = 'off';
            app.PhaseradEditFieldLabel = uilabel(app.Sector20Tab);
            app.PhaseradEditFieldLabel.Text = 'Phase [rad]';
            app.PhaseDESEditField_0 = uieditfield(app.Sector20Tab, 'numeric');
            app.PhaseDESEditField_0.Limits = [-360 360];
            app.AmplitudeRBVEditFieldLabel_0 = uilabel(app.Sector20Tab);
            app.AmplitudeRBVEditFieldLabel_0.Text = 'RBV';
            app.AmplitudeDESEditFieldLabel_0 = uilabel(app.Sector20Tab);
            app.AmplitudeDESEditFieldLabel_0.Text = 'DES';
            app.AmplitudeRBVEditField_0 = uieditfield(app.Sector20Tab, 'numeric');
            app.AmplitudeRBVEditField_0.Editable = 'off';
            app.AmplitudeVEditFieldLabel = uilabel(app.Sector20Tab);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude [V]';
            app.AmplitudeDESEditField_0 = uieditfield(app.Sector20Tab, 'numeric');
            app.TCAVStatusEditFieldLabel = uilabel(app.Sector20Tab);
            app.TCAVStatusEditFieldLabel.Text = 'TCAV Status';
            app.TCAVStatusEditField_0 = uieditfield(app.Sector20Tab, 'text');
            app.TCAVStatusEditField_0.Editable = 'off';
            app.AutophaseButton_0 = uibutton(app.Sector20Tab, 'push');
            app.AutophaseButton_0.Text = 'Autophase';
            app.Sector10Tab = uitab(app.TabGroup_tcav);
            app.Sector10Tab.Title = 'Sector 10';
            app.OnButton_1 = uibutton(app.Sector10Tab, 'push');
            app.OnButton_1.ButtonPushedFcn = createCallbackFcn(app, @OnButton_1Pushed, true);
            app.OnButton_1.Text = 'On';
            app.OffButton_1 = uibutton(app.Sector10Tab, 'push');
            app.OffButton_1.ButtonPushedFcn = createCallbackFcn(app, @OffButton_1Pushed, true);
            app.OffButton_1.Text = 'Off';
            app.TCAVStatusEditFieldLabel_1 = uilabel(app.Sector10Tab);
            app.TCAVStatusEditFieldLabel_1.Text = 'TCAV Status';
            app.TCAVStatusEditField_1 = uieditfield(app.Sector10Tab, 'text');
            app.TCAVStatusEditField_1.Editable = 'off';
            app.PhaseDESEditFieldLabel_1 = uilabel(app.Sector10Tab);
            app.PhaseDESEditFieldLabel_1.Text = 'DES';
            app.PhaseRBVEditFieldLabel_1 = uilabel(app.Sector10Tab);
            app.PhaseRBVEditFieldLabel_1.Text = 'RBV';
            app.PhaseRBVEditField_1 = uieditfield(app.Sector10Tab, 'numeric');
            app.PhaseRBVEditField_1.Editable = 'off';
            app.PhaseradEditFieldLabel_2 = uilabel(app.Sector10Tab);
            app.PhaseradEditFieldLabel_2.Text = 'Phase [rad]';
            app.PhaseDESEditField_1 = uieditfield(app.Sector10Tab, 'numeric');
            app.PhaseDESEditField_1.Limits = [-360 360];
            app.AmplitudeRBVEditFieldLabel_1 = uilabel(app.Sector10Tab);
            app.AmplitudeRBVEditFieldLabel_1.Text = 'RBV';
            app.AmplitudeDESEditFieldLabel_1 = uilabel(app.Sector10Tab);
            app.AmplitudeDESEditFieldLabel_1.Text = 'DES';
            app.AmplitudeRBVEditField_1 = uieditfield(app.Sector10Tab, 'numeric');
            app.AmplitudeRBVEditField_1.Editable = 'off';
            app.AmplitudeVEditFieldLabel_2 = uilabel(app.Sector10Tab);
            app.AmplitudeVEditFieldLabel_2.Text = 'Amplitude [V]';
            app.AmplitudeDESEditField_1 = uieditfield(app.Sector10Tab, 'numeric');
            app.AutophaseButton_1 = uibutton(app.Sector10Tab, 'push');
            app.AutophaseButton_1.Text = 'Autophase';
            app.CalibrationDAQPanel = uipanel(app.UIFigure);
            app.CalibrationDAQPanel.Title = 'Calibration / DAQ';
            app.PhaseEditFieldLabel = uilabel(app.CalibrationDAQPanel);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseStartEditField = uieditfield(app.CalibrationDAQPanel, 'numeric');
            app.PhaseStartEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseStartEditFieldValueChanged, true);
            app.PhaseStopEditField = uieditfield(app.CalibrationDAQPanel, 'numeric');
            app.PhaseStopEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseStopEditFieldValueChanged, true);
            app.PhaseStepsEditField = uieditfield(app.CalibrationDAQPanel, 'numeric');
            app.PhaseStepsEditField.Limits = [0 Inf];
            app.PhaseStepsEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseStepsEditFieldValueChanged, true);
            app.DAQsettingsEditFieldLabel_2 = uilabel(app.CalibrationDAQPanel);
            app.DAQsettingsEditFieldLabel_2.Text = 'Start';
            app.DAQsettingsEditFieldLabel_3 = uilabel(app.CalibrationDAQPanel);
            app.DAQsettingsEditFieldLabel_3.Text = 'Stop';
            app.DAQsettingsEditFieldLabel_4 = uilabel(app.CalibrationDAQPanel);
            app.DAQsettingsEditFieldLabel_4.Text = '# steps';
            app.StartScanButton = uibutton(app.CalibrationDAQPanel, 'push');
            app.StartScanButton.ButtonPushedFcn = createCallbackFcn(app, @StartScanButtonPushed, true);
            app.StartScanButton.Text = 'Start Scan';
            app.CalibrateButton = uibutton(app.CalibrationDAQPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ScanValuesTextAreaLabel = uilabel(app.CalibrationDAQPanel);
            app.ScanValuesTextAreaLabel.Text = 'Scan Values';
            app.ScanValuesTextArea = uitextarea(app.CalibrationDAQPanel);
            app.RunEditFieldLabel = uilabel(app.CalibrationDAQPanel);
            app.RunEditFieldLabel.Text = 'Run';
            app.RunEditField = uieditfield(app.CalibrationDAQPanel, 'numeric');
            app.RunEditField.Limits = [0 Inf];
            app.ExperimentEditFieldLabel = uilabel(app.CalibrationDAQPanel);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.CalibrationDAQPanel, 'text');
            app.CameraViewerPanel = uipanel(app.UIFigure);
            app.CameraViewerPanel.Title = 'Camera Viewer';
            app.UIAxes2 = uiaxes(app.CameraViewerPanel);
            title(app.UIAxes2, {'Camera'; ''})
            xlabel(app.UIAxes2, 'X [mm]')
            ylabel(app.UIAxes2, 'Y [mm]')
            app.StartButton_2 = uibutton(app.CameraViewerPanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButtonUIAxes2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.SelectBeamButton = uibutton(app.CameraViewerPanel, 'state');
            app.SelectBeamButton.ValueChangedFcn = createCallbackFcn(app, @SelectBeamButtonValueChanged, true);
            app.SelectBeamButton.Enable = 'off';
            app.SelectBeamButton.Text = 'Select Beam';
            app.SubtractBackgroundCheckBox = uicheckbox(app.CameraViewerPanel);
            app.SubtractBackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractBackgroundCheckBoxValueChanged, true);
            app.SubtractBackgroundCheckBox.Text = 'Subtract Background';
            app.PVDisplay = uieditfield(app.CameraViewerPanel, 'text');
            app.SelectcameraDropDownLabel = uilabel(app.CameraViewerPanel);
            app.SelectcameraDropDownLabel.Text = 'Select camera';
            app.SelectCameraDropDown = uidropdown(app.CameraViewerPanel);
            app.SelectCameraDropDown.Items = {};
            app.SelectCameraDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCameraDropDownValueChanged, true);
            app.SelectCameraDropDown.Value = {};
            app.ResolutionbitsSpinnerLabel = uilabel(app.CameraViewerPanel);
            app.ResolutionbitsSpinnerLabel.Text = 'Resolution [bits]';
            app.ResolutionbitsSpinner = uispinner(app.CameraViewerPanel);
            app.ResolutionbitsSpinner.Limits = [0 16];
            app.ResolutionbitsSpinner.ValueChangedFcn = createCallbackFcn(app, @ResolutionbitsSpinnerValueChanged, true);
            app.CalibrateEnergyCheckBox = uicheckbox(app.CameraViewerPanel);
            app.CalibrateEnergyCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalibrateEnergyCheckBoxValueChanged, true);
            app.CalibrateEnergyCheckBox.Text = 'Calibrate Energy';
            app.CalibrateTimeCheckBox = uicheckbox(app.CameraViewerPanel);
            app.CalibrateTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalibrateTimeCheckBoxValueChanged, true);
            app.CalibrateTimeCheckBox.Text = 'Calibrate Time';
            app.ShowRawCheckBox = uicheckbox(app.CameraViewerPanel);
            app.ShowRawCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowRawCheckBoxValueChanged, true);
            app.ShowRawCheckBox.Text = 'Show Raw';
            app.ShowBMCheckBox = uicheckbox(app.CameraViewerPanel);
            app.ShowBMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowBMCheckBoxValueChanged, true);
            app.ShowBMCheckBox.Text = 'Show BM';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeProfileTab = uitab(app.TabGroup);
            app.TimeProfileTab.Title = 'Time Profile';
            app.UIAxesTop = uiaxes(app.TimeProfileTab);
            title(app.UIAxesTop, 'Current v Time')
            xlabel(app.UIAxesTop, 'X [mm]')
            ylabel(app.UIAxesTop, 'Signal [a.u.]')
            app.EnergyProfileTab = uitab(app.TabGroup);
            app.EnergyProfileTab.Title = 'Energy Profile';
            app.UIAxesRight = uiaxes(app.EnergyProfileTab);
            title(app.UIAxesRight, {'Current v Energy'; ''})
            xlabel(app.UIAxesRight, 'Y [mm]')
            ylabel(app.UIAxesRight, 'Signal [a.u.]')
            app.PhaseCalibTab = uitab(app.TabGroup);
            app.PhaseCalibTab.Title = 'Phase Calib';
            app.UIAxesPhaseCalib = uiaxes(app.PhaseCalibTab);
            title(app.UIAxesPhaseCalib, 'Title')
            xlabel(app.UIAxesPhaseCalib, 'X')
            ylabel(app.UIAxesPhaseCalib, 'Y')
            app.PhaseCalibPVFilterTab = uitab(app.TabGroup);
            app.PhaseCalibPVFilterTab.Title = 'Phase Calib PV Filter';
            app.UIAxesPhaseCalibPVFilter = uiaxes(app.PhaseCalibPVFilterTab);
            title(app.UIAxesPhaseCalibPVFilter, 'Title')
            xlabel(app.UIAxesPhaseCalibPVFilter, 'X')
            ylabel(app.UIAxesPhaseCalibPVFilter, 'Y')
            app.TimeCalibTab = uitab(app.TabGroup);
            app.TimeCalibTab.Title = 'Time Calib';
            app.UIAxesTimeCalib = uiaxes(app.TimeCalibTab);
            title(app.UIAxesTimeCalib, 'Title')
            xlabel(app.UIAxesTimeCalib, 'X')
            ylabel(app.UIAxesTimeCalib, 'Y')
            app.LogPanel = uipanel(app.UIFigure);
            app.LogPanel.Title = 'Log';
            app.LogTextArea = uitextarea(app.LogPanel);
            app.ClearLogButton = uibutton(app.LogPanel, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.BunchLengthCalibrationButton = uibutton(app.AnalysisPanel, 'push');
            app.BunchLengthCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @BunchLengthCalibrationButtonPushed, true);
            app.BunchLengthCalibrationButton.Text = 'Bunch Length Calibration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tcav_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end